classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        EditField    matlab.ui.control.NumericEditField
        x2Label      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
        xyLabel      matlab.ui.control.Label
        EditField_3  matlab.ui.control.NumericEditField
        y2Label      matlab.ui.control.Label
        EditField_4  matlab.ui.control.NumericEditField
        xLabel       matlab.ui.control.Label
        EditField_5  matlab.ui.control.NumericEditField
        yLabel       matlab.ui.control.Label
        EditField_6  matlab.ui.control.NumericEditField
        Label        matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
        Button       matlab.ui.control.Button
        Label_2      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button.Visible = false;
            app.Label_2.Visible = true;
            app.Label_3.Visible = true;
            A = app.EditField.Value;
            B = app.EditField_2.Value/2;
            C = app.EditField_3.Value;
            D = app.EditField_4.Value/2;
            E = app.EditField_5.Value/2;
            F = app.EditField_6.Value;
            I1 = A + C;
            I2 = det([A,B;B,C]);
            I3 = det([A,B,D; B,C,E; D,E,F]);
            K = det([A,D;D,F])+det([C,E;E,F]);
            [P,O] = eig([A,B;B,C])
            
                        
            if(I2>0)
               if(I1*I3<0)
                   app.Label_2.Text = 'Эллипс';
               elseif(I3==0)
                   app.Label_2.Text = 'Точка';
               else 
                   app.Label_2.Text = 'Мнимый эллипс';
               end
            elseif (I2<0)
                if(I3~=0)
                    app.Label_2.Text = 'Гипербола';
                else
                    app.Label_2.Text = 'Пара пересек. прямых';
                end
            else
                if(I3~=0)
                    app.Label_2.Text = 'Парабола';
                elseif(K<0)
                    app.Label_2.Text = 'Пара паралл. прямых';
                elseif(K==0)
                    app.Label_2.Text = 'Прямая';
                else 
                    app.Label_2.Text = 'Пара мнимых паралл. прямых';
                end
            end


            syms x y x1 y1;
            f = A.*x.^2 + 2.*B.*x.*y + C.*y.^2 + 2.*D.*x + 2.*E.*y + F;
            X1Y1=[x1;y1]
            X2Y2 = P*X1Y1
            x2 = X2Y2(1) 
            y2 = X2Y2(2)
            f_1 = simplify(subs(f,[x,y],[x2,y2]))
            cx = coeffs(f_1,x1, 'All')
            cy = coeffs(f_1,y1, 'All')
            cc = coeffs(f_1,[x1,y1])
            if (length(cx)==3 && length(cy)==3)
                cxy = [cc(1),cy(2),cy(1),cx(2),cx(1)]
            end
            if(length(cx)==2 && length(cy)==3)
                cxy = [cc(1),cy(2),cy(1),cx(1),0]
            end
            if(length(cx)==3 && length(cy)==2)
                cxy = [cc(1),0,cy(2),cx(1),cx(2)]
            end
            if(length(cx)==3 && length(cy)==1)
                cxy = [cc(1),0,0,cx(1),cx(2)]
            end
            if(length(cx)==1 && length(cy)==3)
                cxy = [cc(1),cy(2),cy(1),0,0]
            end
            if(length(cx)==2 && length(cy)==1)
                cxy = [cc(1),0,0,cx(1),0]
            end
            if(length(cx)==1 && length(cy)==2)
                cxy = [cc(1),0,cy(2),0,0]
            end
            a = cxy(5)
            b = cxy(3)
            c = cxy(4)
            d = cxy(2)
            e = cxy(1)
            if ((a~=0 && b~=0))
            q = e*-1+c^2/4/a+d^2/4/b
            f_2 = a/q*x^2 + b/q*y^2
            app.Label_3.Text = [char(f_2),'= 1'];
            ox1 = cxy(4)/(2*cxy(5))
            oy1 = cxy(2)/(2*cxy(3))
            xyn = P\[ox1;oy1]
            ox =xyn(1)*-1
            oy = xyn(2)*-1
            fxx = [1,0]*P
            fx = -1*(x-ox)*fxx(2)+(y-oy)*fxx(1)
            fyy = [0,1]*P
            fy = (x-ox)*fyy(2)-(y-oy)*fyy(1)
            fimplicit(app.UIAxes,[f,fx,fy]);
            end
            if (a==0&&b~=0)
            f_2=(y)^2+c/b*(x)
            app.Label_3.Text = [char(f_2),'= 0'];
            ox1 = -1*e/c+d^2/c/4/b
            oy1 = d/-2/b
            xyn = P\[ox1;oy1]
            ox =xyn(1)
            oy = xyn(2)
            fxx = [1,0]*P
            fx = -1*(x-ox)*fxx(2)+(y-oy)*fxx(1)
            fyy = [0,1]*P
            fy = (x-ox)*fyy(2)-(y-oy)*fyy(1)
            fimplicit(app.UIAxes,[f,fx,fy]);
            end
            if (a~=0&&b==0)
            f_2=(x)^2+c/a*(y)
            app.Label_3.Text = [char(f_2),'= 0'];
            ox1 = -1*e/c+d^2/c/4/a
            oy1 = d/-2/a
            xyn = P\[ox1;oy1]
            ox =xyn(1)
            oy = xyn(2)
            fxx = [1,0]*P
            fx = -1*(x-ox)*fxx(2)+(y-oy)*fxx(1)
            fyy = [0,1]*P
            fy = (x-ox)*fyy(2)-(y-oy)*fyy(1)
            fimplicit(app.UIAxes,[f,fy,fx]);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.x2Label = uilabel(app.UIFigure);
            app.x2Label.Text = {'x^2 +'; ''};
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.xyLabel = uilabel(app.UIFigure);
            app.xyLabel.Text = {'xy +'; ''};
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.y2Label = uilabel(app.UIFigure);
            app.y2Label.Text = {'y^2 +'; ''};
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = {'x +'; ''};
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = {'y +'; ''};
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {' = 0'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'Определить тип'; ''};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end